classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SaveaudioasLabel          matlab.ui.control.Label
        flacCheckBox              matlab.ui.control.CheckBox
        wavCheckBox               matlab.ui.control.CheckBox
        Slider                    matlab.ui.control.Slider
        SliderLabel               matlab.ui.control.Label
        UploadfileButton          matlab.ui.control.Button
        Button_2                  matlab.ui.control.Button
        AudioPlayerLabel          matlab.ui.control.Label
        Button                    matlab.ui.control.StateButton
        SpeechToTextButton        matlab.ui.control.Button
        Lamp                      matlab.ui.control.Lamp
        SpeechToTextLabel         matlab.ui.control.Label
        RecordButton              matlab.ui.control.StateButton
        TextToSpeechButton        matlab.ui.control.Button
        TextArea                  matlab.ui.control.TextArea
        FsEditField               matlab.ui.control.NumericEditField
        FsEditFieldLabel          matlab.ui.control.Label
        TextToSpeechLabel         matlab.ui.control.Label
        ConvertTxtToSpeechButton  matlab.ui.control.Button
        InsertTextEditField       matlab.ui.control.EditField
        InsertTextEditFieldLabel  matlab.ui.control.Label
        PaceSpinner               matlab.ui.control.Spinner
        PaceSpinnerLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        recorder ; % antikeimena poy apo8hkeyontai se epipedo app gia na einai prosbasima apo oles tis synarthseis ths efarmoghs
        file;
        player;
        AFR;
        ADW;
        keepPlaying;
    end
    
    methods (Access = private)


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConvertTxtToSpeechButton
        function ButtonPushed(app, event)
       a = uigetfile(); %anoigei ena dialog gia na ginei upload ena file apo to file manager
       fid = fopen(a); %anoigei to file pou epilexthke
       txt = fscanf(fid,'%s'); %diabazei-metatrepei to txt file se symboloseira
       pace = app.PaceSpinner.Value; %pairnei thn timh poy exoyme dwsei gia to pace mesw gui
       fs = app.FsEditField.Value; %pairnei thn timh poy exoyme dwsei gia to fs mesw gui
       tts(txt,'1',pace,fs); %ekteleitai h synarthsh tts gia thn metatroph tou keimenoy se omilia
       answer = questdlg('Do you want to save the audio file ?','Save Audio'); %dialog box poy rwtaei ton xrhsth ean 8elei na apo8hkeysei to arxeio
       wav = tts(txt,'1',pace,fs); %sthn metablhth wav ekxwrhse to apotelesma ths synarthshs tts
       if answer == 'Yes', audiowrite('sample.wav',wav,fs); end %an h apanthsh toy dialog box einai yes tote apo8hkeyse to arxeio
       fclose(fid); %kleinei to txt file
        end

        % Button pushed function: TextToSpeechButton
        function TextToSpeechButtonPushed2(app, event)
            %a = uigetfile();
            %fid = fopen(a);
            %txt = fscanf(fid,'%s');
            %formatSpec = '%s'
            %txt = extractFileText(a);
            txt = app.InsertTextEditField.Value; %pairnei thn timh poy exoyme dwsei sto plaisio toy gui gia symboloseira
            pace = app.PaceSpinner.Value; %pairnei thn timh poy exoyme dwsei gia to pace mesw gui
            fs = app.FsEditField.Value; %pairnei thn timh poy exoyme dwsei gia to fs mesw gui
            tts(txt,'1',pace,fs); %ekteleitai h synarthsh tts gia thn metatroph tou keimenoy se omilia
            answer = questdlg('Do you want to save the audio file ?','Save Audio'); %dialog box poy rwtaei ton xrhsth ean 8elei na apo8hkeysei to arxeio
            wav = tts(txt,'1',pace,fs); %sthn metablhth wav ekxwrhse to apotelesma ths synarthshs tts
            if answer == 'Yes', audiowrite('sample.wav',wav,fs); end %an h apanthsh toy dialog box einai yes tote apo8hkeyse to arxeio
            %fclose(fid);
        end

        % Value changed function: RecordButton
        function RecordButtonValueChanged(app, event)
            value = app.RecordButton.Value; % h timh toy state button true / false
            %app.currently_recording = true;
            datatype = 'uint8'; %o typos me ton opoio 8a apo8hkeytoyn ta dedomena
            nbits = 16; %o ari8mos twn bit poy 8a parei ws eisodo h synarthshs audiorecorder
            ch = 1; % gia na to hxografhmeno akoysthko kyma na einai mono kai oxi stereo
            fs = app.FsEditField.Value; %h timh poy fs apo to interface
            if value %elegxos an h timh toy state einai true
                app.Lamp.Enable = 'on'; % energopoiei thn lampa
                msgbox('Start of recording','Recording'); % dialogbox gia thn enarxh
                app.recorder = audiorecorder(fs,nbits,ch); % h dhmioyrgia enos antikeimenoy audiorecorder
                record(app.recorder); % enarxh ths leitoyrgias hxografishs 
            else
                stop(app.recorder); %paysh ths hxografishs
                app.Lamp.Enable = 'off'; % apenergopoiei thn lampa
                x = getaudiodata(app.recorder,datatype);% apo8hkeyei topika to apotelesma ths hxografishs
                %app.currently_recording = false;
                answer = questdlg('Do you want to save the audio file ?','Save Audio');
                % dhmioyrgia enos dialog box poy rwtaei ton xrhsth an 8elei
                %na apo8hkeysei ston diskoto hxhtiko arxeio poy exei dhmioyrgh8ei

                    if answer == 'Yes' & app.wavCheckBox.Value, audiowrite('samplerec.wav',x,fs); end
                    if answer == 'Yes' & app.flacCheckBox.Value, audiowrite('samplerec.flac',x,fs); end
                    %ean h apanthsh einai Yes tote apouhkeyse to hxhtiko
                    %analoga me to checkbox poy exei epilex8ei
                    %wav ston disko
            end       
        end

        % Button pushed function: SpeechToTextButton
        function SpeechToTextButtonPushed(app, event)
            a = uigetfile(); %dialogox gia na epilex8ei arxeio
            [~,~,ext] = fileparts(a); %bazei se dianysma ta merh toy arxeioy kai sygakrimena to extension toy
            if strcmp(ext,'.wav') || strcmp(ext,'.flac') %elgxos mese strcmp ean oi symboloseira ext einai idia me thn symboloseira '.wav' h me thn '.flac'
                file_audio = a; %pernaei to file poy epelexe o xrhsths se mia metablhth poy 8a mporoyn na blepoyn thn timh ths kai alles synarthseis toy app
            else
                errordlg('You should give the right type of file'); %alliws emfanizei ena erro message dialog me to mhnyma oti o xrhsths prepei na dwsei to swsto typo arxeioy 
            end   
            txt = wav2vec(audioread(file_audio),app.FsEditField.Value); %ektelei thn leitoyrgia stt mesw ths synarthshs wav2vec
            %txt = char(x);
            msgbox('The transformation to text is ready','Speech To Text');
            answer = questdlg('Do you want to save the text file ?','Save File');% dhmioyrgia enos dialog box poy rwtaei ton xrhsth an 8elei
                %na apo8hkeysei ston diskoto hxhtiko arxeio poy exei dhmioyrgh8ei
               if answer == 'Yes', writematrix(txt,'sttfile.txt'); end %apo8hkeyei to arxeio ean h apanthsh einai yes
        end

        % Button pushed function: UploadfileButton
        function UploadfileButtonPushed(app, event)
            file_audio = uigetfile(); %dialogbox gia na dialexei o xrhsths audio file
            [~,~,ext] = fileparts(file_audio); % returns the path name, file name, and extension for the specified file.
            if strcmp(ext,'.wav') || strcmp(ext,'.flac') %elgxos mese strcmp ean oi symboloseira ext einai idia me thn symboloseira '.wav' h me thn '.flac'
                app.file=file_audio; %pernaei to file poy epelexe o xrhsths se mia metablhth poy 8a mporoyn na blepoyn thn timh ths kai alles synarthseis toy app
            else
                errordlg('You should give the right type of file'); %alliws emfanizei ena erro message dialog me to mhnyma oti o xrhsths prepei na dwsei to swsto typo arxeioy 
            end   


        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            if value %elegxei ean h timh toy button einai true
            app.AFR = dsp.AudioFileReader(app.file); %diabazei to hxhtiko arxeio
            app.ADW = audioDeviceWriter(app.AFR.SampleRate); % grafei ta hxhtika deigmata sthn syskeyh exodoy
            app.Slider.Value = 1; %arxikopoioume thn timh tou volume sto 1
            app.keepPlaying = value; % arxikopoioymai thn timh tou 'flag' sto true
            while ~isDone(app.AFR) && app.keepPlaying == true % while epanalhpsh gia oso den exei teleiwsei to app.AFR kai to flag mas einai true
                audio = app.AFR(); %kalei to antikeimeno app.AFR san na einai synarthsh kai to ekxvrei sto audio
                app.ADW(app.Slider.Value/100 * audio); % paizei to hxhtiko kalwntas to antikeimeno app.ADW kai dinontas san orisma to audio epi thn timh tou volume
                drawnow limitrate % drawnow einai gia na sigoyreytoyme oti to graphic callback tha prospelastei. Auto xreiazetai otan trexoyme megales epanalhpseis - loops kai theloume na diakopsoume thn epanalhpsh gia na prospelasoume to callback kapoioy alloy graphic antikeimenou.
            end
                release(app.AFR); % afou teleiwsei to hxhtiko kleinei to arxeio kai stamataei thn xrhsh tou audio device.
                release(app.ADW);
                app.Button.Value = false;% vazei thn timh tou stete button sto false
            else
                app.keepPlaying = value; %alliws bbazei thn timh tou flag sto false
            end   
                %{
                [x,fs] = audioread(app.file);
                app.player = audioplayer(x,fs);
                play(app.player);
                app.Slider.Value = 1;
                if app.Slider.Value >1
                    for app.Slider.Value:10
                        y = x*app.Slider.Value;
                        app.player = audioplayer(y,fs);
                        play(app.player);
                    end
                end   

                      
            else
                 pause(app.player);
                %}

               
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.keepPlaying = false; %to flag poy xrhsimopoioyme ston player gia na stamathsei me to stop
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PaceSpinnerLabel = uilabel(app.UIFigure);
            app.PaceSpinnerLabel.Text = 'Pace';
            app.PaceSpinner = uispinner(app.UIFigure);
            app.PaceSpinner.Limits = [-10 10];
            app.InsertTextEditFieldLabel = uilabel(app.UIFigure);
            app.InsertTextEditFieldLabel.Text = 'Insert Text';
            app.InsertTextEditField = uieditfield(app.UIFigure, 'text');
            app.ConvertTxtToSpeechButton = uibutton(app.UIFigure, 'push');
            app.ConvertTxtToSpeechButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ConvertTxtToSpeechButton.Text = 'ConvertTxtToSpeech';
            app.TextToSpeechLabel = uilabel(app.UIFigure);
            app.TextToSpeechLabel.Text = 'Text To Speech';
            app.FsEditFieldLabel = uilabel(app.UIFigure);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FsEditField.ValueDisplayFormat = '%.0f';
            app.FsEditField.Value = 16000;
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Οι τιμές του ρυθμού δειγματοληψίας πρέπει να είναι οι ακόλουθες : 8000,11025,12000,16000,22050, 24000,32000,44100,48000'};
            app.TextToSpeechButton = uibutton(app.UIFigure, 'push');
            app.TextToSpeechButton.ButtonPushedFcn = createCallbackFcn(app, @TextToSpeechButtonPushed2, true);
            app.TextToSpeechButton.Text = 'Text To Speech';
            app.RecordButton = uibutton(app.UIFigure, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.RecordButton.Text = 'Record';
            app.SpeechToTextLabel = uilabel(app.UIFigure);
            app.SpeechToTextLabel.Text = 'Speech To Text';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Enable = 'off';
            app.SpeechToTextButton = uibutton(app.UIFigure, 'push');
            app.SpeechToTextButton.ButtonPushedFcn = createCallbackFcn(app, @SpeechToTextButtonPushed, true);
            app.SpeechToTextButton.Text = 'Speech To Text';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '⏯️';
            app.AudioPlayerLabel = uilabel(app.UIFigure);
            app.AudioPlayerLabel.Text = 'Audio Player';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '⏹️';
            app.UploadfileButton = uibutton(app.UIFigure, 'push');
            app.UploadfileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadfileButtonPushed, true);
            app.UploadfileButton.Text = 'Upload file 📂';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = '🔊';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 100];
            app.Slider.MajorTicks = [1 10 20 30 40 50 60 70 80 90 100];
            app.Slider.MajorTickLabels = {'1', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.Slider.MinorTicks = [5 15 25 35 45 55 65 75 85 95];
            app.Slider.Value = 1;
            app.wavCheckBox = uicheckbox(app.UIFigure);
            app.wavCheckBox.Text = 'wav';
            app.wavCheckBox.Value = true;
            app.flacCheckBox = uicheckbox(app.UIFigure);
            app.flacCheckBox.Text = 'flac';
            app.SaveaudioasLabel = uilabel(app.UIFigure);
            app.SaveaudioasLabel.Text = 'Save audio as:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end